classdef AppWSimulink < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DoorStatusTextArea          matlab.ui.control.TextArea
        DoorStatusTextAreaLabel     matlab.ui.control.Label
        Door_Open                   matlab.ui.control.Button
        Door_Close                  matlab.ui.control.Button
        FloorSpinner                matlab.ui.control.Spinner
        FloorSpinnerLabel           matlab.ui.control.Label
        NoPassengersSpinner         matlab.ui.control.Spinner
        NoPassengersSpinnerLabel    matlab.ui.control.Label
        Lamp_2                      matlab.ui.control.Lamp
        DOWNButton                  matlab.ui.control.Button
        UPButton                    matlab.ui.control.Button
        NormalOperationButton       matlab.ui.control.Button
        TextArea_2                  matlab.ui.control.TextArea
        TextArea                    matlab.ui.control.TextArea
        Lamp                        matlab.ui.control.Lamp
        MOVINGButton                matlab.ui.control.Button
        STOPButton                  matlab.ui.control.Button
        Button_9                    matlab.ui.control.Button
        Button_8                    matlab.ui.control.Button
        Button_7                    matlab.ui.control.Button
        Button_6                    matlab.ui.control.Button
        Button_5                    matlab.ui.control.Button
        Button_4                    matlab.ui.control.Button
        Button_3                    matlab.ui.control.Button
        Button_2                    matlab.ui.control.Button
        CurrentFloorEditField       matlab.ui.control.EditField
        CurrentFloorEditFieldLabel  matlab.ui.control.Label
        FloorGauge                  matlab.ui.control.Gauge
    end


    properties (Access = private)
        chart % Description
        CurFloor
    end

    methods (Access = public)

        function updateCounter(app, Cur_Floor, Test, Req_Floor)
            app.CurFloor = Cur_Floor(end);
            app.CurrentFloorEditField.Value = string(app.CurFloor);
            app.FloorGauge.Value = Cur_Floor;
            %             if app.chart.moving == 1
            if Req_Floor ~= Cur_Floor
                app.Lamp.Enable = 'on';
            else
                app.Lamp.Enable = 'off';
            end
        end

        function Logic(app,NxFloor)
            app.chart.Req_Floor = NxFloor;

        end

        function updateDoorStatus(app, Status)
            if Status == 0
                app.DoorStatusTextArea.Value = "Doors Opening";
            elseif Status == 1
                app.DoorStatusTextArea.Value = "Door Open";
            elseif Status == 2
                app.DoorStatusTextArea.Value = "Door Closing";
            elseif Status == 3
                app.DoorStatusTextArea.Value = "Door Closed";
            end
%             app.DoorStatusTextArea.Value = string(Status);
%             app.chart.DoorPush = 0;

        end
        function DoorOperation(app, DoorPush)
            %             app.chart.door = Operation;
            app.chart.DoorPush = DoorPush;
        end
        function Floor_request(app, Req_Floor)
            Logic(app,Req_Floor);
        end

        function Emergency(app,Emg)
            app.chart.Emg = Emg;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, chart)
            app.chart = chart;               % access data in Stateflow chart
            assignin('base','app',app)      % access app from base workspaces
        end

        % Callback function
        function ButtonPushed(app, event)
            app.TextArea.Value = string(event.Source.Text);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            set_param('Simulink_Model','SimulationCommand', 'stop');
        end

        % Button pushed function: Button_2
        function One(app, event)
            Floor_request(app, 1)
        end

        % Callback function: Button_3, UIFigure
        function Two(app, event)
            Floor_request(app, 2)
        end

        % Button pushed function: Button_4
        function Three(app, event)
            Floor_request(app, 3)
        end

        % Button pushed function: Button_5
        function Four(app, event)
            Floor_request(app, 4)
        end

        % Button pushed function: Button_6
        function Five(app, event)
            Floor_request(app, 5)
        end

        % Button pushed function: Button_7
        function Six(app, event)
            Floor_request(app, 6)
        end

        % Button pushed function: Button_8
        function Seven(app, event)
            Floor_request(app, 7)
        end

        % Button pushed function: Button_9
        function Eight(app, event)
            Floor_request(app, 8)
        end

        % Button pushed function: STOPButton
        function STOP(app, event)
            Emergency(app, 1);
        end

        % Button pushed function: NormalOperationButton
        function GO(app, event)
            Emergency(app, 0);
        end

        % Button pushed function: Door_Close
        function DoorClose(app, event)
            DoorOperation(app,1);
        end

        % Button pushed function: Door_Open
        function DoorOpen(app, event)
            DoorOperation(app,1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @Two, true);
            app.FloorGauge = uigauge(app.UIFigure, 'circular');
            app.FloorGauge.Limits = [0 8];
            app.FloorGauge.MajorTicks = [0 1 2 3 4 5 6 7 8];
            app.FloorGauge.MinorTicks = [];
            app.CurrentFloorEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentFloorEditFieldLabel.Text = 'Current Floor';
            app.CurrentFloorEditField = uieditfield(app.UIFigure, 'text');
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @One, true);
            app.Button_2.Text = '1';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Two, true);
            app.Button_3.Text = '2';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Three, true);
            app.Button_4.Text = '3';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Four, true);
            app.Button_5.Text = '4';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Five, true);
            app.Button_6.Text = '5';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Six, true);
            app.Button_7.Text = '6';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Seven, true);
            app.Button_8.Text = '7';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Eight, true);
            app.Button_9.Text = '8';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOP, true);
            app.STOPButton.Text = 'STOP';
            app.MOVINGButton = uibutton(app.UIFigure, 'push');
            app.MOVINGButton.Text = 'MOVING';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp.Enable = 'off';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.NormalOperationButton = uibutton(app.UIFigure, 'push');
            app.NormalOperationButton.ButtonPushedFcn = createCallbackFcn(app, @GO, true);
            app.NormalOperationButton.Text = 'Normal Operation';
            app.UPButton = uibutton(app.UIFigure, 'push');
            app.UPButton.Text = 'UP';
            app.DOWNButton = uibutton(app.UIFigure, 'push');
            app.DOWNButton.Enable = 'off';
            app.DOWNButton.Visible = 'off';
            app.DOWNButton.Text = 'DOWN';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_2.Enable = 'off';
            app.NoPassengersSpinnerLabel = uilabel(app.UIFigure);
            app.NoPassengersSpinnerLabel.Text = 'No. Passengers';
            app.NoPassengersSpinner = uispinner(app.UIFigure);
            app.NoPassengersSpinner.Limits = [1 20];
            app.NoPassengersSpinner.Value = 1;
            app.FloorSpinnerLabel = uilabel(app.UIFigure);
            app.FloorSpinnerLabel.Text = 'Floor';
            app.FloorSpinner = uispinner(app.UIFigure);
            app.FloorSpinner.Limits = [1 8];
            app.FloorSpinner.Value = 1;
            app.Door_Close = uibutton(app.UIFigure, 'push');
            app.Door_Close.ButtonPushedFcn = createCallbackFcn(app, @DoorClose, true);
            app.Door_Close.Text = '><';
            app.Door_Open = uibutton(app.UIFigure, 'push');
            app.Door_Open.ButtonPushedFcn = createCallbackFcn(app, @DoorOpen, true);
            app.Door_Open.Text = '<>';
            app.DoorStatusTextAreaLabel = uilabel(app.UIFigure);
            app.DoorStatusTextAreaLabel.Text = 'Door Status';
            app.DoorStatusTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppWSimulink(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end